classdef DVZ < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        GridLayout                     matlab.ui.container.GridLayout
        LeftPanel                      matlab.ui.container.Panel
        RightPanel                     matlab.ui.container.Panel
        GridLayout2                    matlab.ui.container.GridLayout
        angularVelocityEditField       matlab.ui.control.NumericEditField
        angularvelocityEditFieldLabel  matlab.ui.control.Label
        linearVelocityEditField        matlab.ui.control.NumericEditField
        linearvelocityEditFieldLabel   matlab.ui.control.Label
        StartStopButton                matlab.ui.control.Button
        UIAxes                         matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    % Self-defined properties
    properties (Access = private)
        % Map properties
        xLimit = struct('min', -10, 'max', 10);
        yLimit = struct('min', -10, 'max', 10);
        map_resolution = 0.05;
        Obstacles = struct('x', [], 'y', []);
        Map;

        % Robot properties
        Robot = struct( ...
            'length', 0.78, ...
            'width', 0.51, ...
            'x', 0, ...
            'y', 0, ...
            'theta', 0, ...
            'lin_vel', 0, ...
            'ang_vel', 0, ...
            'lin_vel_limit', 0.7, ...
            'ang_vel_limit', 0.3 ...
            );
        DVZField = struct( ...
            'x', zeros(1, 10000), ...
            'y', zeros(1, 10000), ...
            'ax', 0, ...
            'by', 0, ...
            'kax', 7.8, ...
            'kby', 5.1, ...
            'alpha', linspace(-pi, pi, 10000) ...
            );

        % Real-time simulation properties
        deltaT = 0.01;
        Timer = timer("ExecutionMode", "fixedRate", "BusyMode", "queue");
    end
    
    % Self-defined methods
    methods (Access = private)
        % Obtain x and y values of a rectangle
        function [x, y] = rectangleCoordinates(~, centerX, centerY, height, width)
            % Check if the input values are valid
            if height <= 0 || width <= 0
                error('Height and width must be positive values.');
            end
        
            % Assume the bottom-left corner of the rectangle is at the origin (0,0)
            x = centerX + [-width/2, width/2, width/2, -width/2, -width/2];
            y = centerY + [-height/2, -height/2, height/2, height/2, -height/2];
        end

        % Convert 2D Cartesian coordinates points (x,y)
        % to matrix indices (i,j)
        function [i,j] = coord2matIdx(~, x, y, xMax, yMax, res)
            i = round((yMax-y)/res)+1;
            j = round((xMax+x)/res)+1;
        end
        
        % Update robot position based on velocities
        function updateRobotPose(app)
            app.Robot.x = app.Robot.x + app.deltaT * app.Robot.lin_vel * cos(app.Robot.theta);
            app.Robot.y = app.Robot.y + app.deltaT * app.Robot.lin_vel * sin(app.Robot.theta);
            app.Robot.theta = app.Robot.theta + app.deltaT * app.Robot.ang_vel;
        end

        % Update the DVZ field
        function updateDVZField(app)
            % Update the semimajor and semiminor axes
            app.DVZField.ax = app.DVZField.kax * app.Robot.lin_vel;
            app.DVZField.by = app.DVZField.kby * app.Robot.lin_vel;
            app.DVZField.x = app.Robot.x ...
                + app.DVZField.ax * cos(app.Robot.theta) * cos(app.DVZField.alpha) ...
                - app.DVZField.by * sin(app.Robot.theta) * sin(app.DVZField.alpha);
            app.DVZField.y = app.Robot.y ...
                + app.DVZField.ax * sin(app.Robot.theta) * cos(app.DVZField.alpha) ...
                + app.DVZField.by * cos(app.Robot.theta) * sin(app.DVZField.alpha);
        end

        % Update display values related to the robot status
        function updateEditFields(app)

            if abs(app.Robot.lin_vel) > 1e-04
                app.linearVelocityEditField.Value = app.Robot.lin_vel;
            else
                app.linearVelocityEditField.Value = 0;
            end

            if abs(app.Robot.ang_vel) > 1e-04
                app.angularVelocityEditField.Value = app.Robot.ang_vel;
            else
                app.angularVelocityEditField.Value = 0;
            end

        end

        % Update every plottings
        function updatePlots(app)
            % Plot the body of the mobile robot
            plot(app.UIAxes, app.Robot.x, app.Robot.y, ...
                'o', 'MarkerSize', 10, 'MarkerFaceColor', 'b');
            
            hold(app.UIAxes, 'on');

            % Plot the DVZ field
            plot(app.UIAxes, app.DVZField.x, app.DVZField.y, ':', ...
                'Color', 'g');
            
            % Plot the heading orientation
            plot(app.UIAxes, ...
                [app.Robot.x, app.Robot.x + cos(app.Robot.theta)], ...
                [app.Robot.y, app.Robot.y + sin(app.Robot.theta)], ...
                "Color", 'r' ...
                );

            % Plot the obstacles
            for i = 1:length(app.Obstacles)
                plot(app.UIAxes, app.Obstacles(i).x, app.Obstacles(i).y, ...
                    'Color', 'k');
            end

            hold(app.UIAxes, 'off');
        end

        % Update everything at period `deltaT`
        function timerCallback(app)
            updateRobotPose(app);

            updateDVZField(app);
            
            updateEditFields(app);

            updatePlots(app);

            % plot(app.UIAxes, app.t, sin(app.t+app.theta));
            % app.theta = app.theta + pi/180;
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Set up the timer
            app.Timer.Period = app.deltaT;
            app.Timer.TimerFcn = @(~,~) app.timerCallback;
            
            % Set up the static obstacles
            [app.Obstacles(1).x, app.Obstacles(1).y] = rectangleCoordinates(app, 5, 5, 1, 2);
            [app.Obstacles(2).x, app.Obstacles(2).y] = rectangleCoordinates(app, -5, 5, 1, 2);
            [app.Obstacles(3).x, app.Obstacles(3).y] = rectangleCoordinates(app, -5, -5, 1, 2);
            [app.Obstacles(4).x, app.Obstacles(4).y] = rectangleCoordinates(app, 5, -5, 1, 2);
            
            % Initialize the map
            app.Map = zeros((app.yLimit.max - app.yLimit.min)/app.map_resolution+1, (app.xLimit.max - app.xLimit.min)/app.map_resolution+1);
            for idx = 1:length(app.Obstacles)
                [i_UR, j_UR] = coord2matIdx(app, app.Obstacles(idx).x(3), app.Obstacles(idx).y(3), app.xLimit.max, app.yLimit.max, app.map_resolution);
                [i_UL, j_UL] = coord2matIdx(app, app.Obstacles(idx).x(4), app.Obstacles(idx).y(4), app.xLimit.max, app.yLimit.max, app.map_resolution);
                [i_BL, j_BL] = coord2matIdx(app, app.Obstacles(idx).x(5), app.Obstacles(idx).y(5), app.xLimit.max, app.yLimit.max, app.map_resolution);
                app.Map(i_UL:i_BL, j_UL:j_UR) = ones(length(i_UL:i_BL), length(j_UL:j_UR));
            end
        end

        % Key press function: UIFigure
        function UIFigureKeyPress(app, event)
            % Update velocities based on key press
            switch event.Key
                case 'w'
                    app.Robot.lin_vel = app.Robot.lin_vel + 0.05;
                    if app.Robot.lin_vel >= app.Robot.lin_vel_limit
                        app.Robot.lin_vel = app.Robot.lin_vel_limit;
                    end
                case 's'
                    app.Robot.lin_vel = app.Robot.lin_vel - 0.05;
                    if app.Robot.lin_vel <= -app.Robot.lin_vel_limit
                        app.Robot.lin_vel = -app.Robot.lin_vel_limit;
                    end
                case 'a'
                    app.Robot.ang_vel = app.Robot.ang_vel + 0.01;
                    if app.Robot.ang_vel >= app.Robot.ang_vel_limit
                        app.Robot.ang_vel = app.Robot.ang_vel_limit;
                    end
                case 'd'
                    app.Robot.ang_vel = app.Robot.ang_vel - 0.01;
                    if app.Robot.ang_vel <= -app.Robot.ang_vel_limit
                        app.Robot.ang_vel = -app.Robot.ang_vel_limit;
                    end
                case 'space'
                    app.Robot.lin_vel = 0;
                    app.Robot.ang_vel = 0;
            end
        end

        % Button pushed function: StartStopButton
        function StartStopButtonPushed(app, event)
            if app.StartStopButton.Text == "Start"
                if strcmp(app.Timer.Running,"off")
                    start(app.Timer);
                    app.StartStopButton.Text = "Stop";
                end
            elseif app.StartStopButton.Text == "Stop"
                stop(app.Timer);
                app.StartStopButton.Text = "Start";
            end
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            delete(app);
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {480, 480};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {220, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.UIFigure.KeyPressFcn = createCallbackFcn(app, @UIFigureKeyPress, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.RightPanel = uipanel(app.GridLayout);
            app.GridLayout2 = uigridlayout(app.RightPanel);
            app.UIAxes = uiaxes(app.GridLayout2);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.XLim = [-10 10];
            app.UIAxes.YLim = [-10 10];
            app.StartStopButton = uibutton(app.GridLayout2, 'push');
            app.StartStopButton.ButtonPushedFcn = createCallbackFcn(app, @StartStopButtonPushed, true);
            app.StartStopButton.Text = 'Start';
            app.linearvelocityEditFieldLabel = uilabel(app.GridLayout2);
            app.linearvelocityEditFieldLabel.Text = 'linear velocity';
            app.linearVelocityEditField = uieditfield(app.GridLayout2, 'numeric');
            app.linearVelocityEditField.ValueDisplayFormat = '%11.2g';
            app.linearVelocityEditField.Editable = 'off';
            app.angularvelocityEditFieldLabel = uilabel(app.GridLayout2);
            app.angularvelocityEditFieldLabel.Text = 'angular velocity';
            app.angularVelocityEditField = uieditfield(app.GridLayout2, 'numeric');
            app.angularVelocityEditField.ValueDisplayFormat = '%11.2g';
            app.angularVelocityEditField.Editable = 'off';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DVZ

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end